classdef KillerSudoku < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        TIMESCORELabel                 matlab.ui.control.Label
        countTimer                     matlab.ui.control.Label
        GridLayout5                    matlab.ui.container.GridLayout
        SAVEButton                     matlab.ui.control.Button
        CLOSEButton                    matlab.ui.control.Button
        afteryouputavalueinacellLabel  matlab.ui.control.Label
        PresstheenterbuttonLabel       matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        BigCell                        matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        Cell9                          matlab.ui.container.GridLayout
        EditF99                        matlab.ui.control.NumericEditField
        EditF89                        matlab.ui.control.NumericEditField
        EditF79                        matlab.ui.control.NumericEditField
        EditF98                        matlab.ui.control.NumericEditField
        EditF88                        matlab.ui.control.NumericEditField
        EditF78                        matlab.ui.control.NumericEditField
        EditF97                        matlab.ui.control.NumericEditField
        EditF87                        matlab.ui.control.NumericEditField
        EditF77                        matlab.ui.control.NumericEditField
        Cell8                          matlab.ui.container.GridLayout
        EditF69                        matlab.ui.control.NumericEditField
        EditF59                        matlab.ui.control.NumericEditField
        EditF49                        matlab.ui.control.NumericEditField
        EditF68                        matlab.ui.control.NumericEditField
        EditF58                        matlab.ui.control.NumericEditField
        EditF48                        matlab.ui.control.NumericEditField
        EditF67                        matlab.ui.control.NumericEditField
        EditF57                        matlab.ui.control.NumericEditField
        EditF47                        matlab.ui.control.NumericEditField
        Cell7                          matlab.ui.container.GridLayout
        EditF39                        matlab.ui.control.NumericEditField
        EditF29                        matlab.ui.control.NumericEditField
        EditF19                        matlab.ui.control.NumericEditField
        EditF38                        matlab.ui.control.NumericEditField
        EditF28                        matlab.ui.control.NumericEditField
        EditF18                        matlab.ui.control.NumericEditField
        EditF37                        matlab.ui.control.NumericEditField
        EditF27                        matlab.ui.control.NumericEditField
        EditF17                        matlab.ui.control.NumericEditField
        Cell6                          matlab.ui.container.GridLayout
        EditF96                        matlab.ui.control.NumericEditField
        EditF86                        matlab.ui.control.NumericEditField
        EditF76                        matlab.ui.control.NumericEditField
        EditF95                        matlab.ui.control.NumericEditField
        EditF85                        matlab.ui.control.NumericEditField
        EditF75                        matlab.ui.control.NumericEditField
        EditF94                        matlab.ui.control.NumericEditField
        EditF84                        matlab.ui.control.NumericEditField
        EditF74                        matlab.ui.control.NumericEditField
        Cell5                          matlab.ui.container.GridLayout
        EditF66                        matlab.ui.control.NumericEditField
        EditF56                        matlab.ui.control.NumericEditField
        EditF46                        matlab.ui.control.NumericEditField
        EditF65                        matlab.ui.control.NumericEditField
        EditF55                        matlab.ui.control.NumericEditField
        EditF45                        matlab.ui.control.NumericEditField
        EditF64                        matlab.ui.control.NumericEditField
        EditF54                        matlab.ui.control.NumericEditField
        EditF44                        matlab.ui.control.NumericEditField
        Cell4                          matlab.ui.container.GridLayout
        EditF36                        matlab.ui.control.NumericEditField
        EditF26                        matlab.ui.control.NumericEditField
        EditF16                        matlab.ui.control.NumericEditField
        EditF35                        matlab.ui.control.NumericEditField
        EditF25                        matlab.ui.control.NumericEditField
        EditF15                        matlab.ui.control.NumericEditField
        EditF34                        matlab.ui.control.NumericEditField
        EditF24                        matlab.ui.control.NumericEditField
        EditF14                        matlab.ui.control.NumericEditField
        Cell3                          matlab.ui.container.GridLayout
        EditF93                        matlab.ui.control.NumericEditField
        EditF83                        matlab.ui.control.NumericEditField
        EditF73                        matlab.ui.control.NumericEditField
        EditF92                        matlab.ui.control.NumericEditField
        EditF82                        matlab.ui.control.NumericEditField
        EditF72                        matlab.ui.control.NumericEditField
        EditF91                        matlab.ui.control.NumericEditField
        EditF81                        matlab.ui.control.NumericEditField
        EditF71                        matlab.ui.control.NumericEditField
        Cell2                          matlab.ui.container.GridLayout
        EditF63                        matlab.ui.control.NumericEditField
        EditF53                        matlab.ui.control.NumericEditField
        EditF43                        matlab.ui.control.NumericEditField
        EditF62                        matlab.ui.control.NumericEditField
        EditF52                        matlab.ui.control.NumericEditField
        EditF42                        matlab.ui.control.NumericEditField
        EditF61                        matlab.ui.control.NumericEditField
        EditF51                        matlab.ui.control.NumericEditField
        EditF41                        matlab.ui.control.NumericEditField
        Cell1                          matlab.ui.container.GridLayout
        EditF33                        matlab.ui.control.NumericEditField
        EditF23                        matlab.ui.control.NumericEditField
        EditF13                        matlab.ui.control.NumericEditField
        EditF32                        matlab.ui.control.NumericEditField
        EditF22                        matlab.ui.control.NumericEditField
        EditF12                        matlab.ui.control.NumericEditField
        EditF31                        matlab.ui.control.NumericEditField
        EditF11                        matlab.ui.control.NumericEditField
        EditF21                        matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Timer      % Timer object
      % Define simulation parameters
        simulation_duration = 10; % Duration of simulation in seconds
        time_step = 0.1; % Time step for simulation loop in seconds
        
        % Initialize simulation time
        simulation_time = 0;
        
    end
    
    properties (Access = public)
        puzzle %Set values to start puzzle
        SolvedPuzzle %Set values puzzle solved
        cages %Set numbers cells used to sum
        tag %Set tag component
        value %Set value component
        array = zeros(9,9); % Create array 2d with zero inserts 
        Counter = 0 %Count cells are fill on start in game 
        ArrayCage %Set values from cage to set collors
        CollorCells %Set values from cage to set cells used
    end
       
    methods (Access = public)
        function runTimer(app)
            % Start the timer
            tic;
            checktime = false;
            % Set simulation parameters
            app.time_step = 0.1; % Time step for the simulation loop in seconds
            
            % Initialize simulation time
            app.simulation_time = 0;
            
            % Main simulation loop
            while checktime == false
                % Perform simulation tasks
                if app.simulation_time ==0.4

                    checktime =true;
                     
                end    
               updateTimer(app);
            end
            
          end
    

        function updateTimer(app, ~)
             % Update simulation time
                app.simulation_time = app.simulation_time + app.time_step;
                                
                % Display real time
                app.countTimer.Text = sprintf('%.1f', app.simulation_time);    
          
        end


    end

    
    methods (Access = public)
        %Fill cells 
        function loadpuzzle(app)
            [rows, cols] = size(app.puzzle);
            for row = 1:rows
                for col = 1:cols
                    app.array(row,col)= col * 10 + row;
                    name = "EditF" + row + col;
                     if app.puzzle(col, row) ~= 0 
                        app.Counter = app.Counter + 1;
                        app.(name).Value = app.puzzle(col,row); 
                        app.(name).Editable ="off";
                     end
                    
                end
            end
        end

        %Check ineserts
        function Checkpuzzle(app)
                [rows, cols] = size(app.SolvedPuzzle); 
                counter = 1;
                for row = 1:rows
                    for col = 1:cols
                        name = "EditF" + col + row;
                        var = col * 10 + row;
                        str = num2str(var);

                        if strcmp(app.tag, str)
                             if app.value == app.SolvedPuzzle(row,col)
                                app.(name).Editable ="off";
                                break;
                             else
                                 app.(name).Value = [] ;
                                 break;
                             end

                        end
                         if app.(name).Value > 0 
                            counter = counter + 1;
                            if counter == 81 
                                stop(app.Timer);
                           end    
                        end
                    end
                end
            end
    
    
        function createColorPickers(app,array)
            % Get the size of the array
            numElements = length(array);

             % Generate random initial colors
            initialColors = cell(numElements, 1);
            for i = 1:numElements
                initialColors{i} = generateRandomColor(app);
            end
            
            % Ensure the grid layout has enough rows for the color pickers
            app.GridLayout6.RowHeight = repmat({50}, 1, numElements); % Adjust the number of rows  
            % Loop through the array and create a color picker button for each element
            for i = 1:numElements
                   colorUsed = initialColors{i};
                    uibutton(app.GridLayout6, ...
                    'Text', sprintf(' %d', app.ArrayCage(i).sum), ...  
                    'BackgroundColor', hex2rgb(app,colorUsed), ... % Set the initial color
                    'Enable', 'off', ...
                    'Position', [0 0 100 30], ... % Adjust position and size as needed
                    'Tag', sprintf('ColorButton%d', i)); % Assign a unique tag
                    
                    app.CollorCells = app.ArrayCage(i).cells;
                    CellLength = length(app.CollorCells);
                    for j = 1:CellLength
                      SetCell = fix(app.CollorCells(j)/9);
                      checkCell = mod(app.CollorCells(j),9);
                      disp("Number")
                      disp(app.CollorCells(j));
                      disp("Set Cell");
                      disp(SetCell);
                      disp("Check Cell");
                      disp(checkCell);
                      if checkCell == 0 
                        if app.CollorCells(j)> 9
                            nameF = "EditF"+ app.CollorCells(j);
                            app.(nameF).BackgroundColor = hex2rgb(app,colorUsed);
                        else 
                            nameF = "EditF"+ 1 +app.CollorCells(j);
                            app.(nameF).BackgroundColor = hex2rgb(app,colorUsed);
                        end
                      else
                          if app.CollorCells(j) < 10
                            nameF = "EditF"+ 1 + app.CollorCells(j);
                            app.(nameF).BackgroundColor = hex2rgb(app,colorUsed);
                          
                          else
                              if mod(app.CollorCells(j),10) ~= 0
                                nameF = "EditF"+ app.CollorCells(j);
                                app.(nameF).BackgroundColor = hex2rgb(app,colorUsed);
                              else
                                  if checkCell == SetCell
                                    nameF = "EditF"+ checkCell + 1;
                                    app.(nameF).BackgroundColor = hex2rgb(app,colorUsed);
                                  end    
                                    %na diwr8wsw ta ifs edw 
                              end  

                          end

                      end
                      
                      
                    end
                   
                   
            end
        end
        function hexColor = generateRandomColor(~)
          hexColor = sprintf('#%02X%02X%02X', randi([0, 255]), randi([0, 255]), randi([0, 255]));
        end
   
        function rgb = hex2rgb(~,hex)
            % Convert hex color code to MATLAB RGB format
            hex = hex(2:end); % Remove the '#' character
            rgb = [hex2dec(hex(1:2)) hex2dec(hex(3:4)) hex2dec(hex(5:6))] / 255;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           RulesKillerSudoku();     
           data = load("RulesKillerSudoku.mat","puzzle","grid","cages");
           app.puzzle = data.puzzle;
           app.SolvedPuzzle = data.grid;
           % disp(app.puzzle);
           % disp(app.SolvedPuzzle);
           app.ArrayCage = data.cages;
           createColorPickers(app,data.cages);

           loadpuzzle(app);

            % Set simulation parameters
            app.time_step = 0.1; % Time step for the simulation loop in seconds
            app.simulation_time = 0; % Initialize simulation time
            
            % Create timer object
            app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', app.time_step, ...
                              'TimerFcn', @(~,~) updateTimer(app));
            
            % Start the timer
            start(app.Timer);
        end

        % Value changed function: EditF11, EditF12, EditF13, EditF14, 
        % ...and 77 other components
        function Check(app, event)
                source = event.Source;
                app.tag = source.Tag;
                app.value = source.Value;
                Checkpuzzle(app);
             
                    
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {553, 553};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {337, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.GridLayout2 = uigridlayout(app.GridLayout4);
            app.PresstheenterbuttonLabel = uilabel(app.GridLayout2);
            app.PresstheenterbuttonLabel.Interpreter = 'latex';
            app.PresstheenterbuttonLabel.Text = 'Press the enter button ';
            app.afteryouputavalueinacellLabel = uilabel(app.GridLayout2);
            app.afteryouputavalueinacellLabel.Interpreter = 'latex';
            app.afteryouputavalueinacellLabel.Text = 'after you put a value in a cell';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.CLOSEButton = uibutton(app.GridLayout5, 'push');
            app.CLOSEButton.Text = 'CLOSE';
            app.SAVEButton = uibutton(app.GridLayout5, 'push');
            app.SAVEButton.Enable = 'off';
            app.SAVEButton.Visible = 'off';
            app.SAVEButton.Text = 'SAVE';
            app.countTimer = uilabel(app.GridLayout2);
            app.countTimer.Text = '0';
            app.TIMESCORELabel = uilabel(app.GridLayout2);
            app.TIMESCORELabel.Text = 'TIME SCORE';
            app.RightPanel = uipanel(app.GridLayout);
            app.BigCell = uigridlayout(app.RightPanel);
            app.Cell1 = uigridlayout(app.BigCell);
            app.EditF21 = uieditfield(app.Cell1, 'numeric');
            app.EditF21.Limits = [1 9];
            app.EditF21.AllowEmpty = 'on';
            app.EditF21.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF21.Tag = '21';
            app.EditF21.Value = [];
            app.EditF11 = uieditfield(app.Cell1, 'numeric');
            app.EditF11.Limits = [1 9];
            app.EditF11.AllowEmpty = 'on';
            app.EditF11.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF11.Tag = '11';
            app.EditF11.Value = [];
            app.EditF31 = uieditfield(app.Cell1, 'numeric');
            app.EditF31.Limits = [1 9];
            app.EditF31.AllowEmpty = 'on';
            app.EditF31.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF31.Tag = '31';
            app.EditF31.Value = [];
            app.EditF12 = uieditfield(app.Cell1, 'numeric');
            app.EditF12.Limits = [1 9];
            app.EditF12.AllowEmpty = 'on';
            app.EditF12.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF12.Tag = '12';
            app.EditF12.Value = [];
            app.EditF22 = uieditfield(app.Cell1, 'numeric');
            app.EditF22.Limits = [1 9];
            app.EditF22.AllowEmpty = 'on';
            app.EditF22.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF22.Tag = '22';
            app.EditF22.Value = [];
            app.EditF32 = uieditfield(app.Cell1, 'numeric');
            app.EditF32.Limits = [1 9];
            app.EditF32.AllowEmpty = 'on';
            app.EditF32.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF32.Tag = '32';
            app.EditF32.Value = [];
            app.EditF13 = uieditfield(app.Cell1, 'numeric');
            app.EditF13.Limits = [1 9];
            app.EditF13.AllowEmpty = 'on';
            app.EditF13.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF13.Tag = '13';
            app.EditF13.Value = [];
            app.EditF23 = uieditfield(app.Cell1, 'numeric');
            app.EditF23.Limits = [1 9];
            app.EditF23.AllowEmpty = 'on';
            app.EditF23.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF23.Tag = '23';
            app.EditF23.Value = [];
            app.EditF33 = uieditfield(app.Cell1, 'numeric');
            app.EditF33.Limits = [1 9];
            app.EditF33.AllowEmpty = 'on';
            app.EditF33.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF33.Tag = '33';
            app.EditF33.Value = [];
            app.Cell2 = uigridlayout(app.BigCell);
            app.EditF41 = uieditfield(app.Cell2, 'numeric');
            app.EditF41.Limits = [1 9];
            app.EditF41.AllowEmpty = 'on';
            app.EditF41.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF41.Tag = '41';
            app.EditF41.Value = [];
            app.EditF51 = uieditfield(app.Cell2, 'numeric');
            app.EditF51.Limits = [1 9];
            app.EditF51.AllowEmpty = 'on';
            app.EditF51.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF51.Tag = '51';
            app.EditF51.Value = [];
            app.EditF61 = uieditfield(app.Cell2, 'numeric');
            app.EditF61.Limits = [1 9];
            app.EditF61.AllowEmpty = 'on';
            app.EditF61.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF61.Tag = '61';
            app.EditF61.Value = [];
            app.EditF42 = uieditfield(app.Cell2, 'numeric');
            app.EditF42.Limits = [1 9];
            app.EditF42.AllowEmpty = 'on';
            app.EditF42.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF42.Tag = '42';
            app.EditF42.Value = [];
            app.EditF52 = uieditfield(app.Cell2, 'numeric');
            app.EditF52.AllowEmpty = 'on';
            app.EditF52.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF52.Tag = '52';
            app.EditF52.Value = [];
            app.EditF62 = uieditfield(app.Cell2, 'numeric');
            app.EditF62.Limits = [1 9];
            app.EditF62.AllowEmpty = 'on';
            app.EditF62.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF62.Tag = '62';
            app.EditF62.Value = [];
            app.EditF43 = uieditfield(app.Cell2, 'numeric');
            app.EditF43.Limits = [1 9];
            app.EditF43.AllowEmpty = 'on';
            app.EditF43.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF43.Tag = '43';
            app.EditF43.Value = [];
            app.EditF53 = uieditfield(app.Cell2, 'numeric');
            app.EditF53.Limits = [1 9];
            app.EditF53.AllowEmpty = 'on';
            app.EditF53.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF53.Tag = '53';
            app.EditF53.Value = [];
            app.EditF63 = uieditfield(app.Cell2, 'numeric');
            app.EditF63.Limits = [1 9];
            app.EditF63.AllowEmpty = 'on';
            app.EditF63.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF63.Tag = '63';
            app.EditF63.Value = [];
            app.Cell3 = uigridlayout(app.BigCell);
            app.EditF71 = uieditfield(app.Cell3, 'numeric');
            app.EditF71.Limits = [1 9];
            app.EditF71.AllowEmpty = 'on';
            app.EditF71.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF71.Tag = '71';
            app.EditF71.Value = [];
            app.EditF81 = uieditfield(app.Cell3, 'numeric');
            app.EditF81.Limits = [1 9];
            app.EditF81.AllowEmpty = 'on';
            app.EditF81.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF81.Tag = '81';
            app.EditF81.Value = [];
            app.EditF91 = uieditfield(app.Cell3, 'numeric');
            app.EditF91.Limits = [1 9];
            app.EditF91.AllowEmpty = 'on';
            app.EditF91.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF91.Tag = '91';
            app.EditF91.Value = [];
            app.EditF72 = uieditfield(app.Cell3, 'numeric');
            app.EditF72.Limits = [1 9];
            app.EditF72.AllowEmpty = 'on';
            app.EditF72.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF72.Tag = '72';
            app.EditF72.Value = [];
            app.EditF82 = uieditfield(app.Cell3, 'numeric');
            app.EditF82.Limits = [1 9];
            app.EditF82.AllowEmpty = 'on';
            app.EditF82.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF82.Tag = '82';
            app.EditF82.Value = [];
            app.EditF92 = uieditfield(app.Cell3, 'numeric');
            app.EditF92.Limits = [1 9];
            app.EditF92.AllowEmpty = 'on';
            app.EditF92.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF92.Tag = '92';
            app.EditF92.Value = [];
            app.EditF73 = uieditfield(app.Cell3, 'numeric');
            app.EditF73.Limits = [1 9];
            app.EditF73.AllowEmpty = 'on';
            app.EditF73.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF73.Tag = '73';
            app.EditF73.Value = [];
            app.EditF83 = uieditfield(app.Cell3, 'numeric');
            app.EditF83.Limits = [1 9];
            app.EditF83.AllowEmpty = 'on';
            app.EditF83.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF83.Tag = '83';
            app.EditF83.Value = [];
            app.EditF93 = uieditfield(app.Cell3, 'numeric');
            app.EditF93.Limits = [1 9];
            app.EditF93.AllowEmpty = 'on';
            app.EditF93.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF93.Tag = '93';
            app.EditF93.Value = [];
            app.Cell4 = uigridlayout(app.BigCell);
            app.EditF14 = uieditfield(app.Cell4, 'numeric');
            app.EditF14.Limits = [1 9];
            app.EditF14.AllowEmpty = 'on';
            app.EditF14.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF14.Tag = '14';
            app.EditF14.Value = [];
            app.EditF24 = uieditfield(app.Cell4, 'numeric');
            app.EditF24.Limits = [1 9];
            app.EditF24.AllowEmpty = 'on';
            app.EditF24.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF24.Tag = '24';
            app.EditF24.Value = [];
            app.EditF34 = uieditfield(app.Cell4, 'numeric');
            app.EditF34.Limits = [1 9];
            app.EditF34.AllowEmpty = 'on';
            app.EditF34.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF34.Tag = '34';
            app.EditF34.Value = [];
            app.EditF15 = uieditfield(app.Cell4, 'numeric');
            app.EditF15.Limits = [1 9];
            app.EditF15.AllowEmpty = 'on';
            app.EditF15.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF15.Tag = '15';
            app.EditF15.Value = [];
            app.EditF25 = uieditfield(app.Cell4, 'numeric');
            app.EditF25.Limits = [1 9];
            app.EditF25.AllowEmpty = 'on';
            app.EditF25.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF25.Tag = '25';
            app.EditF25.Value = [];
            app.EditF35 = uieditfield(app.Cell4, 'numeric');
            app.EditF35.Limits = [1 9];
            app.EditF35.AllowEmpty = 'on';
            app.EditF35.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF35.Tag = '35';
            app.EditF35.Value = [];
            app.EditF16 = uieditfield(app.Cell4, 'numeric');
            app.EditF16.Limits = [1 9];
            app.EditF16.AllowEmpty = 'on';
            app.EditF16.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF16.Tag = '16';
            app.EditF16.Value = [];
            app.EditF26 = uieditfield(app.Cell4, 'numeric');
            app.EditF26.Limits = [1 9];
            app.EditF26.AllowEmpty = 'on';
            app.EditF26.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF26.Tag = '26';
            app.EditF26.Value = [];
            app.EditF36 = uieditfield(app.Cell4, 'numeric');
            app.EditF36.Limits = [1 9];
            app.EditF36.AllowEmpty = 'on';
            app.EditF36.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF36.Tag = '36';
            app.EditF36.Value = [];
            app.Cell5 = uigridlayout(app.BigCell);
            app.EditF44 = uieditfield(app.Cell5, 'numeric');
            app.EditF44.Limits = [1 9];
            app.EditF44.AllowEmpty = 'on';
            app.EditF44.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF44.Tag = '44';
            app.EditF44.Value = [];
            app.EditF54 = uieditfield(app.Cell5, 'numeric');
            app.EditF54.Limits = [1 9];
            app.EditF54.AllowEmpty = 'on';
            app.EditF54.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF54.Tag = '54';
            app.EditF54.Value = [];
            app.EditF64 = uieditfield(app.Cell5, 'numeric');
            app.EditF64.Limits = [1 9];
            app.EditF64.AllowEmpty = 'on';
            app.EditF64.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF64.Tag = '64';
            app.EditF64.Value = [];
            app.EditF45 = uieditfield(app.Cell5, 'numeric');
            app.EditF45.Limits = [1 9];
            app.EditF45.AllowEmpty = 'on';
            app.EditF45.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF45.Tag = '45';
            app.EditF45.Value = [];
            app.EditF55 = uieditfield(app.Cell5, 'numeric');
            app.EditF55.Limits = [1 9];
            app.EditF55.AllowEmpty = 'on';
            app.EditF55.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF55.Tag = '55';
            app.EditF55.Value = [];
            app.EditF65 = uieditfield(app.Cell5, 'numeric');
            app.EditF65.Limits = [1 9];
            app.EditF65.AllowEmpty = 'on';
            app.EditF65.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF65.Tag = '65';
            app.EditF65.Value = [];
            app.EditF46 = uieditfield(app.Cell5, 'numeric');
            app.EditF46.Limits = [1 9];
            app.EditF46.AllowEmpty = 'on';
            app.EditF46.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF46.Tag = '46';
            app.EditF46.Value = [];
            app.EditF56 = uieditfield(app.Cell5, 'numeric');
            app.EditF56.Limits = [1 9];
            app.EditF56.AllowEmpty = 'on';
            app.EditF56.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF56.Tag = '56';
            app.EditF56.Value = [];
            app.EditF66 = uieditfield(app.Cell5, 'numeric');
            app.EditF66.Limits = [1 9];
            app.EditF66.AllowEmpty = 'on';
            app.EditF66.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF66.Tag = '66';
            app.EditF66.Value = [];
            app.Cell6 = uigridlayout(app.BigCell);
            app.EditF74 = uieditfield(app.Cell6, 'numeric');
            app.EditF74.Limits = [1 9];
            app.EditF74.AllowEmpty = 'on';
            app.EditF74.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF74.Tag = '74';
            app.EditF74.Value = [];
            app.EditF84 = uieditfield(app.Cell6, 'numeric');
            app.EditF84.Limits = [1 9];
            app.EditF84.AllowEmpty = 'on';
            app.EditF84.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF84.Tag = '84';
            app.EditF84.Value = [];
            app.EditF94 = uieditfield(app.Cell6, 'numeric');
            app.EditF94.Limits = [1 9];
            app.EditF94.AllowEmpty = 'on';
            app.EditF94.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF94.Tag = '94';
            app.EditF94.Value = [];
            app.EditF75 = uieditfield(app.Cell6, 'numeric');
            app.EditF75.Limits = [1 9];
            app.EditF75.AllowEmpty = 'on';
            app.EditF75.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF75.Tag = '75';
            app.EditF75.Value = [];
            app.EditF85 = uieditfield(app.Cell6, 'numeric');
            app.EditF85.Limits = [1 9];
            app.EditF85.AllowEmpty = 'on';
            app.EditF85.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF85.Tag = '85';
            app.EditF85.Value = [];
            app.EditF95 = uieditfield(app.Cell6, 'numeric');
            app.EditF95.Limits = [1 9];
            app.EditF95.AllowEmpty = 'on';
            app.EditF95.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF95.Tag = '95';
            app.EditF95.Value = [];
            app.EditF76 = uieditfield(app.Cell6, 'numeric');
            app.EditF76.Limits = [1 9];
            app.EditF76.AllowEmpty = 'on';
            app.EditF76.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF76.Tag = '76';
            app.EditF76.Value = [];
            app.EditF86 = uieditfield(app.Cell6, 'numeric');
            app.EditF86.Limits = [1 9];
            app.EditF86.AllowEmpty = 'on';
            app.EditF86.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF86.Tag = '86';
            app.EditF86.Value = [];
            app.EditF96 = uieditfield(app.Cell6, 'numeric');
            app.EditF96.Limits = [1 9];
            app.EditF96.AllowEmpty = 'on';
            app.EditF96.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF96.Tag = '96';
            app.EditF96.Value = [];
            app.Cell7 = uigridlayout(app.BigCell);
            app.EditF17 = uieditfield(app.Cell7, 'numeric');
            app.EditF17.Limits = [1 9];
            app.EditF17.AllowEmpty = 'on';
            app.EditF17.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF17.Tag = '17';
            app.EditF17.Value = [];
            app.EditF27 = uieditfield(app.Cell7, 'numeric');
            app.EditF27.Limits = [1 9];
            app.EditF27.AllowEmpty = 'on';
            app.EditF27.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF27.Tag = '27';
            app.EditF27.Value = [];
            app.EditF37 = uieditfield(app.Cell7, 'numeric');
            app.EditF37.Limits = [1 9];
            app.EditF37.AllowEmpty = 'on';
            app.EditF37.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF37.Tag = '37';
            app.EditF37.Value = [];
            app.EditF18 = uieditfield(app.Cell7, 'numeric');
            app.EditF18.Limits = [1 9];
            app.EditF18.AllowEmpty = 'on';
            app.EditF18.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF18.Tag = '18';
            app.EditF18.Value = [];
            app.EditF28 = uieditfield(app.Cell7, 'numeric');
            app.EditF28.Limits = [1 9];
            app.EditF28.AllowEmpty = 'on';
            app.EditF28.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF28.Tag = '28';
            app.EditF28.Value = [];
            app.EditF38 = uieditfield(app.Cell7, 'numeric');
            app.EditF38.Limits = [1 9];
            app.EditF38.AllowEmpty = 'on';
            app.EditF38.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF38.Tag = '38';
            app.EditF38.Value = [];
            app.EditF19 = uieditfield(app.Cell7, 'numeric');
            app.EditF19.Limits = [1 9];
            app.EditF19.AllowEmpty = 'on';
            app.EditF19.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF19.Tag = '19';
            app.EditF19.Value = [];
            app.EditF29 = uieditfield(app.Cell7, 'numeric');
            app.EditF29.Limits = [1 9];
            app.EditF29.AllowEmpty = 'on';
            app.EditF29.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF29.Tag = '29';
            app.EditF29.Value = [];
            app.EditF39 = uieditfield(app.Cell7, 'numeric');
            app.EditF39.Limits = [1 9];
            app.EditF39.AllowEmpty = 'on';
            app.EditF39.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF39.Tag = '39';
            app.EditF39.Value = [];
            app.Cell8 = uigridlayout(app.BigCell);
            app.EditF47 = uieditfield(app.Cell8, 'numeric');
            app.EditF47.Limits = [1 9];
            app.EditF47.AllowEmpty = 'on';
            app.EditF47.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF47.Tag = '47';
            app.EditF47.Value = [];
            app.EditF57 = uieditfield(app.Cell8, 'numeric');
            app.EditF57.Limits = [1 9];
            app.EditF57.AllowEmpty = 'on';
            app.EditF57.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF57.Tag = '57';
            app.EditF57.Value = [];
            app.EditF67 = uieditfield(app.Cell8, 'numeric');
            app.EditF67.Limits = [1 9];
            app.EditF67.AllowEmpty = 'on';
            app.EditF67.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF67.Tag = '67';
            app.EditF67.Value = [];
            app.EditF48 = uieditfield(app.Cell8, 'numeric');
            app.EditF48.Limits = [1 9];
            app.EditF48.AllowEmpty = 'on';
            app.EditF48.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF48.Tag = '48';
            app.EditF48.Value = [];
            app.EditF58 = uieditfield(app.Cell8, 'numeric');
            app.EditF58.Limits = [1 9];
            app.EditF58.AllowEmpty = 'on';
            app.EditF58.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF58.Tag = '58';
            app.EditF58.Value = [];
            app.EditF68 = uieditfield(app.Cell8, 'numeric');
            app.EditF68.Limits = [1 9];
            app.EditF68.AllowEmpty = 'on';
            app.EditF68.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF68.Tag = '68';
            app.EditF68.Value = [];
            app.EditF49 = uieditfield(app.Cell8, 'numeric');
            app.EditF49.Limits = [1 9];
            app.EditF49.AllowEmpty = 'on';
            app.EditF49.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF49.Tag = '49';
            app.EditF49.Value = [];
            app.EditF59 = uieditfield(app.Cell8, 'numeric');
            app.EditF59.Limits = [1 9];
            app.EditF59.AllowEmpty = 'on';
            app.EditF59.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF59.Tag = '59';
            app.EditF59.Value = [];
            app.EditF69 = uieditfield(app.Cell8, 'numeric');
            app.EditF69.Limits = [1 9];
            app.EditF69.AllowEmpty = 'on';
            app.EditF69.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF69.Tag = '69';
            app.EditF69.Value = [];
            app.Cell9 = uigridlayout(app.BigCell);
            app.EditF77 = uieditfield(app.Cell9, 'numeric');
            app.EditF77.Limits = [1 9];
            app.EditF77.AllowEmpty = 'on';
            app.EditF77.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF77.Tag = '77';
            app.EditF77.Value = [];
            app.EditF87 = uieditfield(app.Cell9, 'numeric');
            app.EditF87.Limits = [1 9];
            app.EditF87.AllowEmpty = 'on';
            app.EditF87.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF87.Tag = '87';
            app.EditF87.Value = [];
            app.EditF97 = uieditfield(app.Cell9, 'numeric');
            app.EditF97.Limits = [1 9];
            app.EditF97.AllowEmpty = 'on';
            app.EditF97.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF97.Tag = '97';
            app.EditF97.Value = [];
            app.EditF78 = uieditfield(app.Cell9, 'numeric');
            app.EditF78.Limits = [1 9];
            app.EditF78.AllowEmpty = 'on';
            app.EditF78.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF78.Tag = '78';
            app.EditF78.Value = [];
            app.EditF88 = uieditfield(app.Cell9, 'numeric');
            app.EditF88.Limits = [1 9];
            app.EditF88.AllowEmpty = 'on';
            app.EditF88.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF88.Tag = '88';
            app.EditF88.Value = [];
            app.EditF98 = uieditfield(app.Cell9, 'numeric');
            app.EditF98.Limits = [1 9];
            app.EditF98.AllowEmpty = 'on';
            app.EditF98.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF98.Tag = '98';
            app.EditF98.Value = [];
            app.EditF79 = uieditfield(app.Cell9, 'numeric');
            app.EditF79.Limits = [1 9];
            app.EditF79.AllowEmpty = 'on';
            app.EditF79.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF79.Tag = '79';
            app.EditF79.Value = [];
            app.EditF89 = uieditfield(app.Cell9, 'numeric');
            app.EditF89.Limits = [1 9];
            app.EditF89.AllowEmpty = 'on';
            app.EditF89.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF89.Tag = '89';
            app.EditF89.Value = [];
            app.EditF99 = uieditfield(app.Cell9, 'numeric');
            app.EditF99.Limits = [1 9];
            app.EditF99.AllowEmpty = 'on';
            app.EditF99.ValueChangedFcn = createCallbackFcn(app, @Check, true);
            app.EditF99.Tag = '99';
            app.EditF99.Value = [];
            app.GridLayout6 = uigridlayout(app.BigCell);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KillerSudoku

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end