classdef Lobby < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        SUDOKULabel_2            matlab.ui.control.Label
        CenterPanel              matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        DUIDOKULabel             matlab.ui.control.Label
        KILLERSUDOKULabel        matlab.ui.control.Label
        SUDOKULabel              matlab.ui.control.Label
        STARTDUIDOkUButton       matlab.ui.control.StateButton
        STARTKILLERSUDOKUButton  matlab.ui.control.StateButton
        STARTSUDOKUButton        matlab.ui.control.StateButton
        GAMEMODESLabel           matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        GridLayout6              matlab.ui.container.GridLayout
        Panel                    matlab.ui.container.Panel
        DuidokuTable             matlab.ui.control.Table
        DuidokuLabel             matlab.ui.control.Label
        KillerSudokuTable        matlab.ui.control.Table
        KillerSudokuLabel        matlab.ui.control.Label
        sudokuTable              matlab.ui.control.Table
        SudokuLabel              matlab.ui.control.Label
        DATABASESCORELabel       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
           sudokuView();

           ksView(); 

           duidokuView();
          
           sV = load('sudokuView.mat',"dataS");
           dV = load('duidokuView.mat','dataDui');
           ksV = load('KillerSudokuView.mat',"dataKS");
    
           winner = sV.dataS.winner;
           time = sV.dataS.timer;
           SIZE = length(winner);
           data = cell(SIZE,2);
           for s = 1:SIZE
                w = char( winner(s));
                t = char(time(s));
                data(s,:) = {w,t};
                
           end  
           app.sudokuTable.Data = data;

           winner = ksV.dataKS.winner;
           time = ksV.dataKS.timer;
           SIZE = length(winner);
           data2 = cell(SIZE,2);
           for s = 1:SIZE
                w = char( winner(s));
                t = char(time(s));
                data2(s,:) = {w,t};
                
           end    
           app.KillerSudokuTable.Data = data2;
           wName = dV.dataDui.winner;
           lName = dV.dataDui.loser;
           time = dV.dataDui.timer;
           draw = dV.dataDui.draw;
           SIZE = length(wName);
           data3 = cell(SIZE,4);
           for s = 1:SIZE
                w = char( wName(s));
                l = char(lName(s));
                t = char(time(s));
                d = char(draw(s));
                data3(s,:) = {d,w,l,t};
                
           end    
           app.DuidokuTable.Data = data3;
           

        end

        % Value changed function: STARTSUDOKUButton
        function STARTSUDOKUbtn(app, event)
            %Open Sudoku GAME
            Sudoku;
            
        end

        % Value changed function: STARTKILLERSUDOKUButton
        function STARTKILLERSUDOKUbtn(app, event)
            %Open KillerSudoku GAME
            KillerSudoku;
            
        end

        % Value changed function: STARTDUIDOkUButton
        function STARTDUIDOKUbtn(app, event)
             %Open User
             Users;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {523, 523, 523};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {523, 523};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 303};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.SUDOKULabel_2 = uilabel(app.GridLayout5);
            app.SUDOKULabel_2.Text = 'SUDOKU';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.GAMEMODESLabel = uilabel(app.GridLayout3);
            app.GAMEMODESLabel.Text = 'GAME MODES';
            app.STARTSUDOKUButton = uibutton(app.GridLayout3, 'state');
            app.STARTSUDOKUButton.ValueChangedFcn = createCallbackFcn(app, @STARTSUDOKUbtn, true);
            app.STARTSUDOKUButton.Text = 'START SUDOKU';
            app.STARTKILLERSUDOKUButton = uibutton(app.GridLayout3, 'state');
            app.STARTKILLERSUDOKUButton.ValueChangedFcn = createCallbackFcn(app, @STARTKILLERSUDOKUbtn, true);
            app.STARTKILLERSUDOKUButton.Text = 'START KILLER SUDOKU';
            app.STARTDUIDOkUButton = uibutton(app.GridLayout3, 'state');
            app.STARTDUIDOkUButton.ValueChangedFcn = createCallbackFcn(app, @STARTDUIDOKUbtn, true);
            app.STARTDUIDOkUButton.Text = 'START DUIDOkU';
            app.SUDOKULabel = uilabel(app.GridLayout3);
            app.SUDOKULabel.Text = 'SUDOKU';
            app.KILLERSUDOKULabel = uilabel(app.GridLayout3);
            app.KILLERSUDOKULabel.Text = 'KILLER SUDOKU';
            app.DUIDOKULabel = uilabel(app.GridLayout3);
            app.DUIDOKULabel.Text = 'DUIDOKU';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.DATABASESCORELabel = uilabel(app.GridLayout4);
            app.DATABASESCORELabel.Text = 'DATABASE SCORE';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.Panel = uipanel(app.GridLayout6);
            app.SudokuLabel = uilabel(app.Panel);
            app.SudokuLabel.Text = 'Sudoku';
            app.sudokuTable = uitable(app.Panel);
            app.sudokuTable.ColumnName = {'            NAME'; 'TIME'};
            app.sudokuTable.RowName = {};
            app.KillerSudokuLabel = uilabel(app.Panel);
            app.KillerSudokuLabel.Text = 'KillerSudoku';
            app.KillerSudokuTable = uitable(app.Panel);
            app.KillerSudokuTable.ColumnName = {'NAME'; 'TIME'};
            app.KillerSudokuTable.RowName = {};
            app.DuidokuLabel = uilabel(app.Panel);
            app.DuidokuLabel.Text = 'Duidoku';
            app.DuidokuTable = uitable(app.Panel);
            app.DuidokuTable.ColumnName = {'RESULT'; 'NAME'; 'NAME'; 'TIME'};
            app.DuidokuTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lobby

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end